classdef MSpecAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSpecAnalysisUIFigure           matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        ContactUsMenu                   matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushTool8                       matlab.ui.container.toolbar.PushTool
        PushTool9                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        AnalysisLabel                   matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ImportStatusLabel               matlab.ui.control.Label
        RecentFilesPanel                matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        RecentFileListBox               matlab.ui.control.ListBox
        CreateNewProjectPanel           matlab.ui.container.Panel
        DescriptionEditField            matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        Import_CreateProjectButton      matlab.ui.control.Button
        NumberofMassSpectraEditField    matlab.ui.control.NumericEditField
        NumberofMassSpectraEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HeightField                     matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        WidthField                      matlab.ui.control.NumericEditField
        DimensionLabel                  matlab.ui.control.Label
        DataTypesButtonGroup            matlab.ui.container.ButtonGroup
        Imaging2DButton                 matlab.ui.control.RadioButton
        MassSpectra1DButton             matlab.ui.control.RadioButton
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        Import_ImportButton             matlab.ui.control.Button
        ImportyourmassspectrometrydatatobeginLabel  matlab.ui.control.Label
        WelcometoMSpecLabel             matlab.ui.control.Label
        ClassificationTab               matlab.ui.container.Tab
        ContinuewithoutPreprocessingButton  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ImportedDataPanel               matlab.ui.container.Panel
        ImportedDataTable               matlab.ui.control.Table
        DataTypeLabel                   matlab.ui.control.Label
        DataTypeButtonGroup             matlab.ui.container.ButtonGroup
        Imaging2DButton_2               matlab.ui.control.RadioButton
        MassSpectra1DButton_2           matlab.ui.control.RadioButton
        Image_2                         matlab.ui.control.Image
        HeightField_2                   matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        WidthField_2                    matlab.ui.control.NumericEditField
        DimensionLabel_2                matlab.ui.control.Label
        NumberofMassSpectraEditField_2  matlab.ui.control.NumericEditField
        NumberofMassSpectraEditField_2Label  matlab.ui.control.Label
        ModelInformationPanel           matlab.ui.container.Panel
        ModelInfoTextArea               matlab.ui.control.TextArea
        ImportModelButton               matlab.ui.control.Button
        ModelTypeLabel                  matlab.ui.control.Label
        ModelTypeTree                   matlab.ui.container.Tree
        KNEARESTNEIGHBORKNNNode         matlab.ui.container.TreeNode
        SUPPORTVECTORMACHINESSVMNode    matlab.ui.container.TreeNode
        DECISIONTREESNode               matlab.ui.container.TreeNode
        NAIVEBAYESCLASSIFIERSNode       matlab.ui.container.TreeNode
        ENSEMBLEBaggedTreeNode          matlab.ui.container.TreeNode
        LINEARDISCRIINANTANALYSISLDANode  matlab.ui.container.TreeNode
        ClassificationLabel             matlab.ui.control.Label
        PreprocessingTab                matlab.ui.container.Tab
        Preprocessing_ExportButton_5    matlab.ui.control.Button
        Preprocessing_ExportButton_2    matlab.ui.control.Button
        ViewOptionButtonGroup           matlab.ui.container.ButtonGroup
        Binning_MultipleSpectraButton   matlab.ui.control.RadioButton
        Binning_SelectDataTable         matlab.ui.control.Table
        Binning_SamplePointSpinner      matlab.ui.control.Spinner
        SamplePointSpinnerLabel         matlab.ui.control.Label
        Binning_SingleSpectrumButton    matlab.ui.control.RadioButton
        Binning_AllSpectraButton        matlab.ui.control.RadioButton
        PeakBinningPanel                matlab.ui.container.Panel
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        EdgeListButton                  matlab.ui.control.Button
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        Normalization_MaxIntensityButton  matlab.ui.control.ToggleButton
        Normalization_ReferenceMZEditField  matlab.ui.control.NumericEditField
        ReferenceMZLabel                matlab.ui.control.Label
        Normalization_NoiseLevelButton  matlab.ui.control.ToggleButton
        Normalization_MedianButton      matlab.ui.control.ToggleButton
        Normalization_pnormButton       matlab.ui.control.ToggleButton
        Normalization_pvalueSpinner     matlab.ui.control.Spinner
        pvalueSpinnerLabel              matlab.ui.control.Label
        Normalization_ReferencePeakButton  matlab.ui.control.ToggleButton
        Normalization_ioncountsButton   matlab.ui.control.ToggleButton
        Preprocessing_ExportButton      matlab.ui.control.Button
        SectionofInterestPanel          matlab.ui.container.Panel
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel       matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        PeakAlignmentPanel              matlab.ui.container.Panel
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        MinimumsegementsizeallowedLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        BaselineCorrectionPanel         matlab.ui.container.Panel
        QuantilevalueEditFieldLabel     matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        PreProcessingToolLabel          matlab.ui.control.Label
        PreprocessedDataPlot            matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        Preprocessing_ExportButton_4    matlab.ui.control.Button
        Preprocessing_ExportButton_3    matlab.ui.control.Button
        ScoreTable                      matlab.ui.control.Table
        ScoreLabel                      matlab.ui.control.Label
        ResultLabel                     matlab.ui.control.Label
        ResultTable                     matlab.ui.control.Table
        ClassificationResultsLabel      matlab.ui.control.Label
        PredictionResultPlot            matlab.ui.control.UIAxes
        VisualizationTab                matlab.ui.container.Tab
        VisualizeDataButton_2           matlab.ui.control.Button
        VisualizePanel                  matlab.ui.container.Panel
        ImagingPlot                     matlab.ui.control.UIAxes
        OptionButtonGroup               matlab.ui.container.ButtonGroup
        PredictedClassScoresButton_2    matlab.ui.control.ToggleButton
        PredictedClassesButton_2        matlab.ui.control.ToggleButton
        DisplayPanel                    matlab.ui.container.Panel
        BlueDropDown                    matlab.ui.control.DropDown
        BlueDropDownLabel               matlab.ui.control.Label
        GreenDropDown                   matlab.ui.control.DropDown
        GreenDropDownLabel              matlab.ui.control.Label
        RedDropDown                     matlab.ui.control.DropDown
        RedDropDownLabel                matlab.ui.control.Label
        VisualizationLabel              matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        ProjectInfoTab                  matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Label                           matlab.ui.control.Label
        AboutMSpecLabel                 matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        AboutYourProjectPanel           matlab.ui.container.Panel
        DataExportPanel                 matlab.ui.container.Panel
        StepsLabel_2                    matlab.ui.control.Label
        ExportCSVFilesButton            matlab.ui.control.Button
        Export_PreprocessingCheckBox    matlab.ui.control.CheckBox
        Export_ClassificationCheckBox   matlab.ui.control.CheckBox
        ProjectInfo_CreatedDate         matlab.ui.control.EditField
        CreatedLabel                    matlab.ui.control.Label
        ReportGenerationPanel           matlab.ui.container.Panel
        StepsLabel                      matlab.ui.control.Label
        GeneratePDFReportButton         matlab.ui.control.Button
        PreprocessingCheckBox           matlab.ui.control.CheckBox
        ClassificationResultsCheckBox   matlab.ui.control.CheckBox
        EditButton                      matlab.ui.control.StateButton
        ProjectInfo_DescriptionTextArea  matlab.ui.control.TextArea
        DescriptionTextAreaLabel_2      matlab.ui.control.Label
        ProjectInfo_ImportedFileEditField  matlab.ui.control.EditField
        ImportedFileEditFieldLabel      matlab.ui.control.Label
        ProjectInfo_ProjectNameEditField  matlab.ui.control.EditField
        ProjectNameEditField_2Label     matlab.ui.control.Label
    end

    
    properties (Access = public)
        CurrentProject
        MdlImportApp ModelImportApp
        BinningDisplay='Single';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MSpecAnalysisController.retrieveModelTypes(app);
            MSpecAnalysisController.getRecentFiles(app);
        end

        % Selection changed function: ModelTypeTree
        function ModelTypeTreeSelectionChanged(app, event)
            selectedNodes = app.ModelTypeTree.SelectedNodes;
            if selectedNodes.Parent == app.ModelTypeTree
                % do nothing
                % to be added later
            else
                MSpecAnalysisController.retrieveModelInfo(app);
            end
        end

        % Button pushed function: ImportModelButton
        function ImportModelButtonPushed(app, event)
            app.MdlImportApp = ModelImportApp(app);
        end

        % Button pushed function: Import_ImportButton
        function Import_ImportButtonPushed(app, event)
            MSpecAnalysisController.importData(app);
        end

        % Button pushed function: Import_CreateProjectButton
        function Import_CreateProjectButtonPushed(app, event)
            MSpecAnalysisController.createProject (app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            MSpecAnalysisController.startPreprocessing(app);
        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            app.Binning_SamplePointSpinner.Enable = false;
            app.Binning_SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.Binning_AllSpectraButton
                    app.BinningDisplay='All';
                case app.Binning_SingleSpectrumButton
                    app.BinningDisplay='Single';
                    app.Binning_SamplePointSpinner.Enable = true;
                otherwise
                    app.BinningDisplay='Multiple';
                    app.Binning_SelectDataTable.Enable = 'on';
            end
            AnalysisVisualization.plotPreprocessedData(app);
        end

        % Value changed function: Binning_SamplePointSpinner
        function Binning_SamplePointSpinnerValueChanged(app, event)
            AnalysisVisualization.plotPreprocessedData(app);
        end

        % Button pushed function: Preprocessing_ExportButton_2
        function Preprocessing_ExportButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.ResultsTab;
        end

        % Button pushed function: VisualizeDataButton_2
        function VisualizeDataButtonPushed(app, event)
            Classification.startImaging(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            Classification.startImaging(app);
        end

        % Value changed function: WidthField
        function WidthFieldValueChanged(app, event)
            MSpecAnalysisController.calculateCol(app);
        end

        % Value changed function: HeightField
        function HeightFieldValueChanged(app, event)
            MSpecAnalysisController.calculateRow(app);
        end

        % Value changed function: RedDropDown
        function RedDropDownValueChanged(app, event)
            Classification.startImaging(app);
        end

        % Value changed function: GreenDropDown
        function GreenDropDownValueChanged(app, event)
            Classification.startImaging(app);
        end

        % Value changed function: BlueDropDown
        function BlueDropDownValueChanged(app, event)
             Classification.startImaging(app);
        end

        % Button pushed function: ContinuewithoutPreprocessingButton
        function ContinuewithoutPreprocessingButtonPushed(app, event)
            MSpecAnalysisController.startWithoutPreprocessing(app);
        end

        % Selection changed function: DataTypesButtonGroup
        function DataTypesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypesButtonGroup.SelectedObject;
            switch selectedButton
                case app.Imaging2DButton
                    app.HeightField.Editable = 'on';
                    app.WidthField.Editable = 'on';
                otherwise
                    app.HeightField.Editable = 'off';
                    app.WidthField.Editable = 'off';
            end
        end

        % Clicked callback: PushTool8
        function PushTool8Clicked(app, event)
            MSpecAnalysisController.saveChanges(app);
        end

        % Clicked callback: PushTool9
        function PushTool9Clicked(app, event)
            MSpecAnalysisController.saveAs(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            MSpecAnalysisController.loadRecentFiles(app);
        end

        % Button pushed function: Preprocessing_ExportButton_3
        function Preprocessing_ExportButton_3Pushed(app, event)
            Classification.runPrediction(app);
        end

        % Callback function
        function Preprocessing_ExportButtonPushed(app, event)
            AnalysisDataExport.exportPreprocessedData(app);
        end

        % Button pushed function: Preprocessing_ExportButton_5
        function Preprocessing_ExportButton_5Pushed(app, event)
            AnalysisDataExport.exportBinnedData(app);
        end

        % Button pushed function: GeneratePDFReportButton
        function GeneratePDFReportButtonPushed(app, event)
            AnalysisDataExport.generatePDFReport(app);
        end

        % Button pushed function: ExportCSVFilesButton
        function ExportCSVFilesButtonPushed(app, event)
            AnalysisDataExport.exportAllData(app);
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Menu selected function: ContactUsMenu
        function ContactUsMenuSelected(app, event)
             msgbox({'Thank you for all the love and support for MSpec! ♥';'';'Please feel free to email with any comments, questions, suggestions or concerns';'Email: piradaboonna@gmail.com';'Email: mink.ost26@gmail.com'},'Contact Us');
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
            MSpecAnalysisController.importData(app);
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
             app.TabGroup.SelectedTab = app.ImportTab;
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            AnalysisDataExport.exportAllData(app);
        end

        % Clicked callback: PushTool7
        function PushTool7Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            AnalysisDataExport.generatePDFReport(app);
        end

        % Clicked callback: PushTool6
        function PushTool6Clicked(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
            MSpecAnalysisController.importData(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            MSpecAnalysisController.saveChanges(app);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
             MSpecAnalysisController.saveAs(app);
        end

        % Callback function: ExitMenu, MSpecAnalysisUIFigure
        function MSpecAnalysisUIFigureCloseRequest(app, event)
            if ~isempty(app.CurrentProject)
                MSpecAnalysisController.requestExit(app);
            else
                delete(app);
            end
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
            value = app.EditButton.Value;
            if value==1
                % Enable Edit Field
                app.EditButton.Text = 'Save';
                app.ProjectInfo_DescriptionTextArea.Editable = true;
            else
                % Save
                app.CurrentProject.Description = app.ProjectInfo_DescriptionTextArea.Value;
                app.EditButton.Text = 'Edit';
                app.ProjectInfo_DescriptionTextArea.Editable = false;
            end
        end

        % Button pushed function: EdgeListButton
        function EdgeListButtonPushed(app, event)
            msg = sprintf('%.4f ', app.CurrentProject.PreprocessParameters.ImportedEdgeList);
            msgbox(msg)
        end

        % Button pushed function: Preprocessing_ExportButton_4
        function Preprocessing_ExportButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.VisualizationTab;
        end

        % Cell edit callback: Binning_SelectDataTable
        function Binning_SelectDataTableCellEdit(app, event)
            AnalysisVisualization.plotPreprocessedData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSpecAnalysisUIFigure = uifigure('Visible', 'off');
            app.MSpecAnalysisUIFigure.Name = 'MSpec Analysis';
            app.MSpecAnalysisUIFigure.Icon = 'MSA_icon.png';
            app.MSpecAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @MSpecAnalysisUIFigureCloseRequest, true);
            app.Menu = uimenu(app.MSpecAnalysisUIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @MSpecAnalysisUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.MSpecAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.ContactUsMenu = uimenu(app.HelpMenu);
            app.ContactUsMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactUsMenuSelected, true);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Toolbar = uitoolbar(app.MSpecAnalysisUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New Project'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'plus-red.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open Project'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'Import.png';
            app.PushTool3.Separator = 'on';
            app.PushTool8 = uipushtool(app.Toolbar);
            app.PushTool8.Tooltip = {'Save'};
            app.PushTool8.ClickedCallback = createCallbackFcn(app, @PushTool8Clicked, true);
            app.PushTool8.Icon = 'icons8-save-96.png';
            app.PushTool8.Separator = 'on';
            app.PushTool9 = uipushtool(app.Toolbar);
            app.PushTool9.Tooltip = {'Save As'};
            app.PushTool9.ClickedCallback = createCallbackFcn(app, @PushTool9Clicked, true);
            app.PushTool9.Icon = 'save_as.png';
            app.PushTool9.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'download-red.png';
            app.PushTool4.Separator = 'on';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.ClickedCallback = createCallbackFcn(app, @PushTool7Clicked, true);
            app.PushTool7.Icon = 'report-red.png';
            app.PushTool7.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Icon = 'info-red.png';
            app.PushTool6.Separator = 'on';
            app.TabGroup = uitabgroup(app.MSpecAnalysisUIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.WelcometoMSpecLabel = uilabel(app.ImportTab);
            app.WelcometoMSpecLabel.Text = 'Welcome to MSpec !';
            app.ImportyourmassspectrometrydatatobeginLabel = uilabel(app.ImportTab);
            app.ImportyourmassspectrometrydatatobeginLabel.Text = 'Import your mass spectrometry data to begin :)';
            app.Import_ImportButton = uibutton(app.ImportTab, 'push');
            app.Import_ImportButton.ButtonPushedFcn = createCallbackFcn(app, @Import_ImportButtonPushed, true);
            app.Import_ImportButton.Text = 'Import';
            app.CreateNewProjectPanel = uipanel(app.ImportTab);
            app.CreateNewProjectPanel.Title = 'Create New Project';
            app.ProjectNameEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.CreateNewProjectPanel, 'text');
            app.DataTypesButtonGroup = uibuttongroup(app.CreateNewProjectPanel);
            app.DataTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypesButtonGroupSelectionChanged, true);
            app.DataTypesButtonGroup.Title = 'Data Types';
            app.MassSpectra1DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.MassSpectra1DButton.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton.Value = true;
            app.Imaging2DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.Imaging2DButton.Text = 'Imaging (2D)';
            app.DimensionLabel = uilabel(app.CreateNewProjectPanel);
            app.DimensionLabel.Text = 'Dimension';
            app.WidthField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.WidthField.Limits = [0 Inf];
            app.WidthField.ValueChangedFcn = createCallbackFcn(app, @WidthFieldValueChanged, true);
            app.WidthField.Editable = 'off';
            app.xLabel = uilabel(app.CreateNewProjectPanel);
            app.xLabel.Text = 'x';
            app.HeightField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.HeightField.ValueChangedFcn = createCallbackFcn(app, @HeightFieldValueChanged, true);
            app.HeightField.Editable = 'off';
            app.Image = uiimage(app.CreateNewProjectPanel);
            app.Image.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image.ImageSource = 'info_icon.png';
            app.NumberofMassSpectraEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.NumberofMassSpectraEditFieldLabel.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.NumberofMassSpectraEditField.Editable = 'off';
            app.Import_CreateProjectButton = uibutton(app.CreateNewProjectPanel, 'push');
            app.Import_CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButtonPushed, true);
            app.Import_CreateProjectButton.Enable = 'off';
            app.Import_CreateProjectButton.Text = 'Create';
            app.DescriptionTextAreaLabel = uilabel(app.CreateNewProjectPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionEditField = uitextarea(app.CreateNewProjectPanel);
            app.RecentFilesPanel = uipanel(app.ImportTab);
            app.RecentFilesPanel.Title = 'Recent Files';
            app.RecentFileListBox = uilistbox(app.RecentFilesPanel);
            app.OpenButton = uibutton(app.RecentFilesPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ImportStatusLabel = uilabel(app.ImportTab);
            app.ImportStatusLabel.Text = '';
            app.Image2 = uiimage(app.ImportTab);
            app.Image2.ImageSource = 'MspecAnalysis_logo.png';
            app.AnalysisLabel = uilabel(app.ImportTab);
            app.AnalysisLabel.Text = 'Analysis';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationLabel = uilabel(app.ClassificationTab);
            app.ClassificationLabel.Text = 'Classification';
            app.ModelTypeTree = uitree(app.ClassificationTab);
            app.ModelTypeTree.SelectionChangedFcn = createCallbackFcn(app, @ModelTypeTreeSelectionChanged, true);
            app.KNEARESTNEIGHBORKNNNode = uitreenode(app.ModelTypeTree);
            app.KNEARESTNEIGHBORKNNNode.Text = 'K-NEAREST NEIGHBOR (KNN)';
            app.SUPPORTVECTORMACHINESSVMNode = uitreenode(app.ModelTypeTree);
            app.SUPPORTVECTORMACHINESSVMNode.Text = 'SUPPORT VECTOR MACHINES (SVM)';
            app.DECISIONTREESNode = uitreenode(app.ModelTypeTree);
            app.DECISIONTREESNode.Text = 'DECISION TREES';
            app.NAIVEBAYESCLASSIFIERSNode = uitreenode(app.ModelTypeTree);
            app.NAIVEBAYESCLASSIFIERSNode.Text = 'NAIVE BAYES CLASSIFIERS';
            app.ENSEMBLEBaggedTreeNode = uitreenode(app.ModelTypeTree);
            app.ENSEMBLEBaggedTreeNode.Text = 'ENSEMBLE (Bagged Tree)';
            app.LINEARDISCRIINANTANALYSISLDANode = uitreenode(app.ModelTypeTree);
            app.LINEARDISCRIINANTANALYSISLDANode.Text = 'LINEAR DISCRIINANT ANALYSIS (LDA)';
            app.ModelTypeLabel = uilabel(app.ClassificationTab);
            app.ModelTypeLabel.Text = 'Model Type';
            app.ImportModelButton = uibutton(app.ClassificationTab, 'push');
            app.ImportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelButtonPushed, true);
            app.ImportModelButton.Text = 'Import Model';
            app.ModelInformationPanel = uipanel(app.ClassificationTab);
            app.ModelInformationPanel.Title = 'Model Information';
            app.ModelInfoTextArea = uitextarea(app.ModelInformationPanel);
            app.ModelInfoTextArea.Editable = 'off';
            app.ImportedDataPanel = uipanel(app.ClassificationTab);
            app.ImportedDataPanel.Title = 'Imported Data';
            app.NumberofMassSpectraEditField_2Label = uilabel(app.ImportedDataPanel);
            app.NumberofMassSpectraEditField_2Label.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.NumberofMassSpectraEditField_2.Editable = 'off';
            app.DimensionLabel_2 = uilabel(app.ImportedDataPanel);
            app.DimensionLabel_2.Text = 'Dimension';
            app.WidthField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.WidthField_2.Editable = 'off';
            app.xLabel_2 = uilabel(app.ImportedDataPanel);
            app.xLabel_2.Text = 'x';
            app.HeightField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.HeightField_2.Editable = 'off';
            app.Image_2 = uiimage(app.ImportedDataPanel);
            app.Image_2.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image_2.ImageSource = 'info_icon.png';
            app.DataTypeButtonGroup = uibuttongroup(app.ImportedDataPanel);
            app.MassSpectra1DButton_2 = uiradiobutton(app.DataTypeButtonGroup);
            app.MassSpectra1DButton_2.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton_2.Value = true;
            app.Imaging2DButton_2 = uiradiobutton(app.DataTypeButtonGroup);
            app.Imaging2DButton_2.Text = 'Imaging (2D)';
            app.DataTypeLabel = uilabel(app.ImportedDataPanel);
            app.DataTypeLabel.Text = 'Data Type';
            app.ImportedDataTable = uitable(app.ImportedDataPanel);
            app.ImportedDataTable.ColumnName = '';
            app.ImportedDataTable.RowName = {};
            app.StartButton = uibutton(app.ClassificationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ContinuewithoutPreprocessingButton = uibutton(app.ClassificationTab, 'push');
            app.ContinuewithoutPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuewithoutPreprocessingButtonPushed, true);
            app.ContinuewithoutPreprocessingButton.Text = 'Continue without Preprocessing';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.PreprocessedDataPlot = uiaxes(app.PreprocessingTab);
            title(app.PreprocessedDataPlot, 'Preprocessed Data')
            xlabel(app.PreprocessedDataPlot, 'Mass/Charge (m/z)')
            ylabel(app.PreprocessedDataPlot, 'Relative Intensity')
            zlabel(app.PreprocessedDataPlot, 'Z')
            app.PreProcessingToolLabel = uilabel(app.PreprocessingTab);
            app.PreProcessingToolLabel.Text = 'Pre-Processing Tool';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_WindowSizeEditField.Editable = 'off';
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_StepsizeEditField.Editable = 'off';
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Limits = [0 Inf];
            app.Preprocessing_QuantilevalueEditField.Editable = 'off';
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.PeakAlignmentPanel = uipanel(app.PreprocessingTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Editable = 'off';
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Editable = 'off';
            app.MinimumsegementsizeallowedLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegementsizeallowedLabel.Text = {'Minimum segement'; 'size allowed'};
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Editable = 'off';
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.SectionofInterestPanel = uipanel(app.PreprocessingTab);
            app.SectionofInterestPanel.Title = 'Section of Interest';
            app.StartingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.Preprocessing_StartingpointEditField.Editable = 'off';
            app.EndingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.Preprocessing_EndingpointEditField.Editable = 'off';
            app.Preprocessing_ExportButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton.Text = ' Export Preprocessed Data';
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.NormalizationMethodsButtonGroup.Enable = 'off';
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.Normalization_ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ioncountsButton.Text = 'ion counts';
            app.Normalization_ioncountsButton.Value = true;
            app.Normalization_ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ReferencePeakButton.Text = 'Reference Peak';
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.Normalization_pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.Normalization_pvalueSpinner.Limits = [1 Inf];
            app.Normalization_pvalueSpinner.Value = 1;
            app.Normalization_pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_pnormButton.Text = 'p-norm';
            app.Normalization_MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MedianButton.Text = 'Median';
            app.Normalization_NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_NoiseLevelButton.Text = 'Noise Level';
            app.ReferenceMZLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.ReferenceMZLabel.Text = {'Reference'; 'M/Z'};
            app.Normalization_ReferenceMZEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.Normalization_ReferenceMZEditField.ValueDisplayFormat = '%5.4f';
            app.Normalization_ReferenceMZEditField.Editable = 'off';
            app.Normalization_MaxIntensityButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MaxIntensityButton.Text = 'Maximum Intensity';
            app.PeakBinningPanel = uipanel(app.PreprocessingTab);
            app.PeakBinningPanel.Title = 'Peak Binning';
            app.EdgeListButton = uibutton(app.PeakBinningPanel, 'push');
            app.EdgeListButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeListButtonPushed, true);
            app.EdgeListButton.Text = 'Edge List';
            app.NumberofBinsEditFieldLabel = uilabel(app.PeakBinningPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.PeakBinningPanel, 'numeric');
            app.ViewOptionButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.Binning_AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Binning_AllSpectraButton.Text = 'All Spectra';
            app.Binning_SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Binning_SingleSpectrumButton.Text = 'Single Spectrum';
            app.Binning_SingleSpectrumButton.Value = true;
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.Binning_SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.Binning_SamplePointSpinner.Limits = [1 80];
            app.Binning_SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @Binning_SamplePointSpinnerValueChanged, true);
            app.Binning_SamplePointSpinner.Value = 1;
            app.Binning_SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.Binning_SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.Binning_SelectDataTable.RowName = {};
            app.Binning_SelectDataTable.ColumnSortable = true;
            app.Binning_SelectDataTable.ColumnEditable = [false true];
            app.Binning_SelectDataTable.CellEditCallback = createCallbackFcn(app, @Binning_SelectDataTableCellEdit, true);
            app.Binning_SelectDataTable.Enable = 'off';
            app.Binning_MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Binning_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.Preprocessing_ExportButton_2 = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_2Pushed, true);
            app.Preprocessing_ExportButton_2.Text = 'Next';
            app.Preprocessing_ExportButton_5 = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton_5.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_5Pushed, true);
            app.Preprocessing_ExportButton_5.Text = ' Export Binned Data';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PredictionResultPlot = uiaxes(app.ResultsTab);
            title(app.PredictionResultPlot, 'Title')
            xlabel(app.PredictionResultPlot, 'X')
            ylabel(app.PredictionResultPlot, 'Y')
            zlabel(app.PredictionResultPlot, 'Z')
            app.ClassificationResultsLabel = uilabel(app.ResultsTab);
            app.ClassificationResultsLabel.Text = 'Classification Results';
            app.ResultTable = uitable(app.ResultsTab);
            app.ResultTable.ColumnName = '';
            app.ResultTable.RowName = {};
            app.ResultLabel = uilabel(app.ResultsTab);
            app.ResultLabel.Text = 'Result';
            app.ScoreLabel = uilabel(app.ResultsTab);
            app.ScoreLabel.Text = 'Score';
            app.ScoreTable = uitable(app.ResultsTab);
            app.ScoreTable.ColumnName = '';
            app.ScoreTable.RowName = {};
            app.Preprocessing_ExportButton_3 = uibutton(app.ResultsTab, 'push');
            app.Preprocessing_ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_3Pushed, true);
            app.Preprocessing_ExportButton_3.Text = 'Predict';
            app.Preprocessing_ExportButton_4 = uibutton(app.ResultsTab, 'push');
            app.Preprocessing_ExportButton_4.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_4Pushed, true);
            app.Preprocessing_ExportButton_4.Text = 'Next';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.UIAxes5 = uiaxes(app.VisualizationTab);
            title(app.UIAxes5, 'Selected Data Point')
            xlabel(app.UIAxes5, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes5, 'Relative Intensity')
            zlabel(app.UIAxes5, 'Z')
            app.VisualizationLabel = uilabel(app.VisualizationTab);
            app.VisualizationLabel.Text = 'Visualization';
            app.DisplayPanel = uipanel(app.VisualizationTab);
            app.DisplayPanel.Title = 'Display';
            app.RedDropDownLabel = uilabel(app.DisplayPanel);
            app.RedDropDownLabel.Text = 'Red';
            app.RedDropDown = uidropdown(app.DisplayPanel);
            app.RedDropDown.ValueChangedFcn = createCallbackFcn(app, @RedDropDownValueChanged, true);
            app.GreenDropDownLabel = uilabel(app.DisplayPanel);
            app.GreenDropDownLabel.Text = 'Green';
            app.GreenDropDown = uidropdown(app.DisplayPanel);
            app.GreenDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenDropDownValueChanged, true);
            app.BlueDropDownLabel = uilabel(app.DisplayPanel);
            app.BlueDropDownLabel.Text = 'Blue';
            app.BlueDropDown = uidropdown(app.DisplayPanel);
            app.BlueDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueDropDownValueChanged, true);
            app.OptionButtonGroup = uibuttongroup(app.VisualizationTab);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.PredictedClassesButton_2 = uitogglebutton(app.OptionButtonGroup);
            app.PredictedClassesButton_2.Text = 'Predicted Classes';
            app.PredictedClassesButton_2.Value = true;
            app.PredictedClassScoresButton_2 = uitogglebutton(app.OptionButtonGroup);
            app.PredictedClassScoresButton_2.Text = 'Predicted Class Scores';
            app.VisualizePanel = uipanel(app.VisualizationTab);
            app.ImagingPlot = uiaxes(app.VisualizePanel);
            title(app.ImagingPlot, 'Imaging')
            app.ImagingPlot.XTick = [];
            app.ImagingPlot.XTickLabel = '';
            app.ImagingPlot.YTick = [];
            app.ImagingPlot.YTickLabel = '';
            app.ImagingPlot.ZTick = [];
            app.VisualizeDataButton_2 = uibutton(app.VisualizationTab, 'push');
            app.VisualizeDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizeDataButtonPushed, true);
            app.VisualizeDataButton_2.Text = 'Visualize Data';
            app.ProjectInfoTab = uitab(app.TabGroup);
            app.ProjectInfoTab.Title = 'Project Info';
            app.AboutYourProjectPanel = uipanel(app.ProjectInfoTab);
            app.AboutYourProjectPanel.Title = 'About Your Project';
            app.ProjectNameEditField_2Label = uilabel(app.AboutYourProjectPanel);
            app.ProjectNameEditField_2Label.Text = 'Project Name';
            app.ProjectInfo_ProjectNameEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ProjectNameEditField.Editable = 'off';
            app.ImportedFileEditFieldLabel = uilabel(app.AboutYourProjectPanel);
            app.ImportedFileEditFieldLabel.Text = 'Imported File';
            app.ProjectInfo_ImportedFileEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ImportedFileEditField.Editable = 'off';
            app.DescriptionTextAreaLabel_2 = uilabel(app.AboutYourProjectPanel);
            app.DescriptionTextAreaLabel_2.Text = 'Description';
            app.ProjectInfo_DescriptionTextArea = uitextarea(app.AboutYourProjectPanel);
            app.ProjectInfo_DescriptionTextArea.Editable = 'off';
            app.EditButton = uibutton(app.AboutYourProjectPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Text = 'Edit';
            app.ReportGenerationPanel = uipanel(app.AboutYourProjectPanel);
            app.ReportGenerationPanel.Title = 'Report Generation';
            app.ClassificationResultsCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.ClassificationResultsCheckBox.Text = 'Classification Results';
            app.ClassificationResultsCheckBox.Value = true;
            app.PreprocessingCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PreprocessingCheckBox.Text = 'Pre-processing';
            app.PreprocessingCheckBox.Value = true;
            app.GeneratePDFReportButton = uibutton(app.ReportGenerationPanel, 'push');
            app.GeneratePDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFReportButtonPushed, true);
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.StepsLabel = uilabel(app.ReportGenerationPanel);
            app.StepsLabel.Text = 'Steps';
            app.CreatedLabel = uilabel(app.AboutYourProjectPanel);
            app.CreatedLabel.Text = 'Created';
            app.ProjectInfo_CreatedDate = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_CreatedDate.Editable = 'off';
            app.DataExportPanel = uipanel(app.AboutYourProjectPanel);
            app.DataExportPanel.Title = 'Data Export';
            app.Export_ClassificationCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_ClassificationCheckBox.Text = 'Classification Results';
            app.Export_ClassificationCheckBox.Value = true;
            app.Export_PreprocessingCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PreprocessingCheckBox.Text = 'Pre-processing';
            app.Export_PreprocessingCheckBox.Value = true;
            app.ExportCSVFilesButton = uibutton(app.DataExportPanel, 'push');
            app.ExportCSVFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVFilesButtonPushed, true);
            app.ExportCSVFilesButton.Text = 'Export CSV Files';
            app.StepsLabel_2 = uilabel(app.DataExportPanel);
            app.StepsLabel_2.Text = 'Steps';
            app.Image2_2 = uiimage(app.ProjectInfoTab);
            app.Image2_2.ImageSource = 'Mspec_Application.png';
            app.AboutMSpecLabel = uilabel(app.ProjectInfoTab);
            app.AboutMSpecLabel.Text = 'About MSpec';
            app.Label = uilabel(app.ProjectInfoTab);
            app.Label.Text = {'MSpec is an all-in-one solution for mass spectrometry data analysis.'; 'The application features tools such as pre-processing, normalization, binning,'; 'and classification to aid the user in the analysis of mass spectral data.'; 'The function parameters are also customizable to fit the user''s preferences.'; 'The processed data from each tool can also be exported as csv files and a final report'; 'of each project can also be generated and exported as pdf files.'; ''; 'For more information, please consult the MSpec documentation. '; 'If you need further assistance, please contact us via our email.'};
            app.Hyperlink = uihyperlink(app.ProjectInfoTab);
            app.Hyperlink.URL = 'https://sites.google.com/view/mspec/';
            app.Hyperlink.Text = 'Documentation';
            % Show the figure after all components are created
            app.MSpecAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSpecAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSpecAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSpecAnalysisUIFigure)
        end
    end
end