classdef MZPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MZPickerUIFigure               matlab.ui.Figure
        SelectReferenceMZValueButtonGroup  matlab.ui.container.ButtonGroup
        ClickontheplottobeginorenteravaluedirectlytothetextboxLabel  matlab.ui.control.Label
        SelectedMZValueEditField       matlab.ui.control.NumericEditField
        SelectedMZValueEditFieldLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        MSPlot                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
           CallingApp % Main app object
           IsValid = false; % if the input MZ can be found in data
           MZValues
           MZValueIndex
    end
    
    methods (Access = private)
        
        function result = findNearestNumberInList(app, number)
            sprintf('%d',number)
            [~,idx]=min(abs(app.MZValues-number));
            app.MZValueIndex = idx;
            result=app.MZValues(idx);
        end
        
        function updateMSPlot(app, selectedMZ)
            cla(app.MSPlot);
            plot(app.MSPlot,app.CallingApp.CurrentProject.RawData.RawMzValues, app.CallingApp.CurrentProject.PreprocessedData.BaselinedSpectra)
            xline(app.MSPlot,selectedMZ,'-.r',{'Selected','M/Z'});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object startupFcn(app, mainapp, mzValues, intensities)
            app.CallingApp = mainapp;
            app.MZValues = app.CallingApp.CurrentProject.RawData.RawMzValues;
            % init plot
            app.MSPlot.XLim = [app.CallingApp.CurrentProject.RawData.MinIntensity app.CallingApp.CurrentProject.RawData.MaxIntensity];
            currentMZ = findNearestNumberInList(app,app.CallingApp.Normalization_ReferenceMZEditField.Value);
            updateMSPlot(app, currentMZ);
            app.SelectedMZValueEditField.Value = currentMZ;

            %plot(app.MSPlot,app.CallingApp.CurrentProject.RawData.RawMzValues, app.CallingApp.CurrentProject.PreprocessedData.BaselinedSpectra)
            %app.MZDropDown.Items = string(app.CallingApp.CurrentProject.RawData.RawMzValues);
            
            set(app.MSPlot, 'ButtonDownFcn', {@mouseClick});
            function mouseClick(~,~)
                    %cla(app.MSPlot);
                    %plot(app.MSPlot,app.CallingApp.CurrentProject.RawData.RawMzValues, app.CallingApp.CurrentProject.PreprocessedData.BaselinedSpectra)
                    cp=get(app.MSPlot,'CurrentPoint');
                    selectedMZ = findNearestNumberInList(app, cp(1,1));
                    %xline(app.MSPlot,cp(1,1),'-.r',{'Selected','M/Z'});
                    updateMSPlot(app, selectedMZ);
                    app.SelectedMZValueEditField.Value = selectedMZ;
            end
        end

        % Close request function: MZPickerUIFigure
        function MZPickerUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.MZPickerUIFigure,'Are you sure you want to cancel?','Confirm Close',...
                        'Icon','warning');
            if selection == "OK"
                % Delete the dialog box  
                app.CallingApp.NormalizationMethodsButtonGroup.SelectedObject = app.CallingApp.Normalization_ioncountsButton;
                % Enable the Plot Options button in main app
                app.CallingApp.Normalization_EditRefMZ.Enable = 'on';
                delete(app)  
            else
                % do nothing
            end
            
        end

        % Value changed function: SelectedMZValueEditField
        function SelectedMZValueEditFieldValueChanged(app, event)
            value = app.SelectedMZValueEditField.Value;
            selectedMZ = findNearestNumberInList(app, value);
            app.SelectedMZValueEditField.Value = selectedMZ;
            updateMSPlot(app, selectedMZ);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
           app.CallingApp.Normalization_ReferenceMZEditField.Value = app.SelectedMZValueEditField.Value;
           app.CallingApp.CurrentProject.PreprocessedData.ReferencePeak = app.SelectedMZValueEditField.Value;
           app.CallingApp.CurrentProject.PreprocessedData.ReferencePeakIndex = app.MZValueIndex;
           % Delete the dialog box  
           delete(app)            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            selection = uiconfirm(app.MZPickerUIFigure,'Are you sure you want to cancel?','Confirm Close',...
                        'Icon','warning');
            if selection == "OK"
                % Delete the dialog box  
                app.CallingApp.NormalizationMethodsButtonGroup.SelectedObject = app.CallingApp.Normalization_ioncountsButton;
                delete(app)  
            else
                % do nothing
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MZPickerUIFigure = uifigure('Visible', 'off');
            app.MZPickerUIFigure.Name = 'M/Z Picker';
            app.MZPickerUIFigure.Icon = 'Edit.png';
            app.MZPickerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MZPickerUIFigureCloseRequest, true);
            app.MSPlot = uiaxes(app.MZPickerUIFigure);
            title(app.MSPlot, 'Mass Spectra')
            xlabel(app.MSPlot, 'Mass/Charge (m/z)')
            ylabel(app.MSPlot, 'Relative Intensity')
            zlabel(app.MSPlot, 'Z')
            app.OKButton = uibutton(app.MZPickerUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MZPickerUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectReferenceMZValueButtonGroup = uibuttongroup(app.MZPickerUIFigure);
            app.SelectReferenceMZValueButtonGroup.Title = 'Select Reference M/Z Value';
            app.SelectedMZValueEditFieldLabel = uilabel(app.SelectReferenceMZValueButtonGroup);
            app.SelectedMZValueEditFieldLabel.Text = ' Selected M/Z Value';
            app.SelectedMZValueEditField = uieditfield(app.SelectReferenceMZValueButtonGroup, 'numeric');
            app.SelectedMZValueEditField.ValueDisplayFormat = '%5.4f';
            app.SelectedMZValueEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedMZValueEditFieldValueChanged, true);
            app.ClickontheplottobeginorenteravaluedirectlytothetextboxLabel = uilabel(app.SelectReferenceMZValueButtonGroup);
            app.ClickontheplottobeginorenteravaluedirectlytothetextboxLabel.Text = 'Click on the plot to begin or enter a value directly to the text box';
            % Show the figure after all components are created
            app.MZPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MZPicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MZPickerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MZPickerUIFigure)
        end
    end
end