classdef Mix_Prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSPECAppUIFigure               matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        ImportDataMenu_2               matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        PreprocessingTab               matlab.ui.container.Tab
        NumberofspectraEditFieldLabel  matlab.ui.control.Label
        NumberofspectraEditField       matlab.ui.control.NumericEditField
        BaselineCorrectionPanel        matlab.ui.container.Panel
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        WindowSizeEditField            matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        QuantilevalueEditFieldLabel    matlab.ui.control.Label
        QuantilevalueEditField         matlab.ui.control.NumericEditField
        AlignmentPanel                 matlab.ui.container.Panel
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        ReferenceSpectrumEditField     matlab.ui.control.NumericEditField
        MinimumsegementsizeallowedLabel  matlab.ui.control.Label
        MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        MaximumshiftallowedEditField   matlab.ui.control.NumericEditField
        PlotPanel                      matlab.ui.container.Panel
        SpectrumtodisplayEditFieldLabel  matlab.ui.control.Label
        SpectrumtodisplayEditField     matlab.ui.control.EditField
        SectionofinterestLabel         matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        ExportCSVButton_2              matlab.ui.control.Button
        StartingpointEditFieldLabel    matlab.ui.control.Label
        StartingpointEditField         matlab.ui.control.EditField
        EndingpointEditFieldLabel      matlab.ui.control.Label
        EndingpointEditField           matlab.ui.control.EditField
        UIAxes_4                       matlab.ui.control.UIAxes
        PeakDetectionTab               matlab.ui.container.Tab
        PeakDetectionPanel             matlab.ui.container.Panel
        BASEEditFieldLabel             matlab.ui.control.Label
        BASEEditField                  matlab.ui.control.NumericEditField
        HEIGHTFILTEREditFieldLabel     matlab.ui.control.Label
        HEIGHTFILTEREditField          matlab.ui.control.NumericEditField
        MULTIPLIEREditFieldLabel       matlab.ui.control.Label
        MULTIPLIEREditField            matlab.ui.control.NumericEditField
        PeakThresholdEditFieldLabel    matlab.ui.control.Label
        PeakThresholdEditField         matlab.ui.control.NumericEditField
        SpectrumtodisplayEditField_3Label  matlab.ui.control.Label
        SpectrumtodisplayEditField_3   matlab.ui.control.EditField
        UIAxes_5                       matlab.ui.control.UIAxes
        BiningPanel                    matlab.ui.container.Panel
        CUTOFFEditFieldLabel           matlab.ui.control.Label
        CUTOFFEditField                matlab.ui.control.NumericEditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        CriterionDropDownLabel         matlab.ui.control.Label
        CriterionDropDown              matlab.ui.control.DropDown
        SpectrumtodisplayEditField_2Label  matlab.ui.control.Label
        SpectrumtodisplayEditField_2   matlab.ui.control.EditField
        UIAxes_7                       matlab.ui.control.UIAxes
        UITable_2                      matlab.ui.control.Table
        ExportCSVButton                matlab.ui.control.Button
        DataNormalizationTab           matlab.ui.container.Tab
        RawDataPlot                    matlab.ui.control.UIAxes
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        ioncountsButton                matlab.ui.control.ToggleButton
        ReferencePeakButton            matlab.ui.control.ToggleButton
        PeakSpinnerLabel               matlab.ui.control.Label
        PeakSpinner                    matlab.ui.control.Spinner
        pvalueSpinnerLabel             matlab.ui.control.Label
        pvalueSpinner                  matlab.ui.control.Spinner
        ExclusionofmassrangesCheckBox  matlab.ui.control.CheckBox
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.NumericEditField
        UpperEditFieldLabel            matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.NumericEditField
        NowSelectLabel                 matlab.ui.control.Label
        pnormButton                    matlab.ui.control.ToggleButton
        MedianButton                   matlab.ui.control.ToggleButton
        NoiseLevelButton               matlab.ui.control.ToggleButton
        NormalizedDataPlot             matlab.ui.control.UIAxes
        ViewOptionButtonGroup          matlab.ui.container.ButtonGroup
        AllSpectraButton               matlab.ui.control.RadioButton
        SingleSpectrumButton           matlab.ui.control.RadioButton
        SamplePointSpinnerLabel        matlab.ui.control.Label
        SamplePointSpinner             matlab.ui.control.Spinner
        SelectDataTable                matlab.ui.control.Table
        MultipleSpectraButton          matlab.ui.control.RadioButton
        ExportFilesPanel               matlab.ui.container.Panel
        GeneratePDFReportButton        matlab.ui.control.Button
        ExportCSVNormalizedDataButton  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ImagingTab                     matlab.ui.container.Tab
        Imaging_NormalizedDataPlot     matlab.ui.control.UIAxes
        FeaturePeakLabel               matlab.ui.control.Label
        FeaturePeakSlider              matlab.ui.control.Slider
        SelectedPeakLabel              matlab.ui.control.Label
        Imaging_Display                matlab.ui.control.UIAxes
        ColorMapDropDownLabel          matlab.ui.control.Label
        ColorMapDropDown               matlab.ui.control.DropDown
        Imaging_SelectedDataPlot       matlab.ui.control.UIAxes
        ColorScaleOptionListBoxLabel   matlab.ui.control.Label
        ColorScaleOptionListBox        matlab.ui.control.ListBox
        AnalyticsTab                   matlab.ui.container.Tab
        SelectClassTable               matlab.ui.control.Table
        Imaging_Display_2              matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Mink part
        RawImportData %raw imported data
        RawMzValues %raw mz
        RawSpectraIntensities %raw y
        MinIntensity %min x
        MaxIntensity %max x
        WindowSize %window size
        StepSize %step size
        QuantileValue %quantile value
        BaselinedSpectra %baselined y
        DisplayingSpectra %spectra to display
        NumberOfSpectra %number of spectra
        ReferenceSpectrum %alignment refernce spectrum
        SegmentSize %alignment segment size
        ShiftAllowance %alignment shift alowance
        AlignedSpectra %aligned spectra
        MinPosition %min pos to divide segment
        SegmentLag %segment lag
        ShiftedSegment %shifted segment
        SectionStart %starting point of section of interest
        SectionEnd %ending point of section of interest
        SectionOfInterest %section of interest
        AlignedSectionOfInterest %section of interest
        
        %Sabai part
        PreproceesedImportData %raw imported data
        PreproceesedMzValues %raw mz
        PreproceesedSpectraIntensities %raw y
        Base
        Multiplier
        HeightFilter
        DetectedPeak
        PeakThreshold
        CutThresholdPeak
        Cutoff
        Criterion
        CMZ
        PR
        
        
        %Earn part
        DataArray3 % Our imported data
        XAxisPlot % Our x-axis data
        RowNumber
        ColumnNumber
        NormalizeMethod = 'Sum'; % default normalize method
        NormalizeDisplay = 'All';
        NormalizedArray
        ReferencePeak
        FeaturePeak = 1;
        FileName = '';
        NormalizationNormValue = 1;
        RegressionDegree = 1;
        ImageArray;
    end
    
    methods (Access = private)
        
        %Mink Part
        function baseline(app)
            align(app);
            baselined = msbackadj(app.RawMzValues,transpose(app.AlignedSpectra),'STEPSIZE', app.StepSize, 'WINDOWSIZE', app.WindowSize,'QuantileValue',app.QuantileValue,'SmoothMethod','lowess');        
            baselined = max(baselined,0);
            app.BaselinedSpectra = mslowess(app.RawMzValues,baselined);
        end

        function align(app)
            sample = app.RawSpectraIntensities;
            spectra = transpose(sample);
            reference = spectra(app.ReferenceSpectrum,:);
            segSize = app.SegmentSize;
            shift = app.ShiftAllowance;
            
            if length(reference)~=length(spectra)
                error('Reference and spectra of unequal lengths');
            elseif length(reference)== 1
                error('Reference cannot be of length 1');
            end
            if nargin==3
                shift = length(reference);
            end
            for i=1:size(spectra,1)
                startpos = 1;
                aligned =[];
                while startpos <= length(spectra)
                    endpos=startpos+(segSize*2);
                    if endpos >=length(spectra)
                        samseg= spectra(i,startpos:length(spectra));
                        refseg= reference(1,startpos:length(spectra));
                    else
                        samseg = spectra(i,startpos+segSize:endpos-1);
                        refseg = reference(1,startpos+segSize:endpos-1);
                        findMin(app,samseg,refseg);
                        minpos = app.MinPosition;
                        endpos = startpos+minpos+segSize;
                        samseg = spectra(i,startpos:endpos);
                        refseg = reference(1,startpos:endpos);
                    end
                    FFTcorr(app,samseg,refseg,shift);
                    lag = app.SegmentLag;
                    move(app,samseg,lag);
                    aligned = [aligned app.ShiftedSegment];
                    startpos=endpos+1;
                end
                app.AlignedSpectra(i,:) = aligned;
            end
        end
        
        function FFTcorr(app,spectrum, target, shift)
            %padding
            M=size(target,2);
            diff = 1000000;
            for i=1:20
                curdiff=((2^i)-M);
                if (curdiff > 0 && curdiff<diff)
                    diff = curdiff;
                end
            end
            
            target(1,M+diff)=0;
            spectrum(1,M+diff)=0;
            M= M+diff;
            X=fft(target);
            Y=fft(spectrum);
            R=X.*conj(Y);
            R=R./(M);
            rev=ifft(R);
            vals=real(rev);
            maxpos = 1;
            maxi = -1;
            if M<shift
                shift = M;
            end
            
            for i = 1:shift
                if (vals(1,i) > maxi)
                    maxi = vals(1,i);
                    maxpos = i;
                end
                if (vals(1,length(vals)-i+1) > maxi)
                    maxi = vals(1,length(vals)-i+1);
                    maxpos = length(vals)-i+1;
                end
            end
        
            if maxi < 0.1
                lag =0;
            end
            if maxpos > length(vals)/2
               lag = maxpos-length(vals)-1;
            else
               lag =maxpos-1;
            end
            app.SegmentLag = lag;
        end

        function move(app, seg, lag)
            
            if (lag == 0) || (lag >= length(seg))
                movedSeg = seg;
            end
            
            if lag > 0
                ins = ones(1,lag)*seg(1);
                movedSeg = [ins seg(1:(length(seg) - lag))];
            elseif lag < 0
                lag = abs(lag);
                ins = ones(1,lag)*seg(length(seg));
                movedSeg = [seg((lag+1):length(seg)) ins];
            end
            app.ShiftedSegment = movedSeg;
        end
        
        function findMin(app, samseg,refseg)
        
            [Cs,Is]=sort(samseg);
            [Cr,Ir]=sort(refseg);
            minposA = [];
            minInt = [];
            for i=1:round(length(Cs)/20)
                for j=1:round(length(Cs)/20)
                    if Ir(j)==Is(i);
                        minpos = Is(i);
                    end
                end
            end
            app.MinPosition = Is(1,1);
        end


        %Earn Part
        function updateNormalizedArray(app)
            % Data Normalization
                
            app.NormalizedArray = app.DataArray3;

            switch app.NormalizeMethod % Get Tag of selected object.
                case 'Sum'
                    for j = 1:app.ColumnNumber
                        colj = app.NormalizedArray(:,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./sum(colj);
                    end
                 case 'Area'
                    for j = 1:app.ColumnNumber
                        colj = app.NormalizedArray(:,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./trapz(app.XAxisPlot, colj);
                    end
                 case 'Norm'
                    for j = 1:app.ColumnNumber
                        factor = norm(app.NormalizedArray(:,j),app.NormalizationNormValue);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                    end
                 case 'Median'
                     for j = 1:app.ColumnNumber
                        factor = median(app.NormalizedArray(:,j));
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                     end
                 case 'Noise'
                    for j = 1:app.ColumnNumber
                        % Noise Level
                        DifVector = diff(app.NormalizedArray(:,j));
                        % universal thresholding
                        MedOfDif = median(DifVector);
                        e = abs(DifVector-MedOfDif);
                        factor = median(e);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                    end
                 otherwise %peak
                    for j = 1:app.ColumnNumber
                        ref = app.NormalizedArray(app.ReferencePeak,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./ref;
                    end
           end
                
           setDefaultFeaturePeak(app);
           plotNormalizedData(app);
           DisplayDataTable(app);
           
        end
        
        function setDefaultFeaturePeak(app)
            SDNorm = std(app.NormalizedArray,0,2); % dim = 2 means for each row
            [Max,IndexMax] = max(SDNorm);
            app.FeaturePeak = IndexMax;
            app.FeaturePeakSlider.Value = double(IndexMax);
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
        end
        
        function setDefaultReferencePeak(app)
            SD = std(app.DataArray3,0,2); % dim = 2 means for each row
            [Max,IndexMax] = max(SD);
            app.ReferencePeak = IndexMax;
            app.PeakSpinner.Value = double(IndexMax);
        end
        
        
        function plotNormalizedData(app)
            cla(app.NormalizedDataPlot)
            % Plotting Normalized Data
            
            switch app.NormalizeDisplay
                case 'All'
                    hold(app.NormalizedDataPlot,"on");
                    for k = 1:app.ColumnNumber
                        stem(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                    end
                    hold(app.NormalizedDataPlot,"off");
                case 'Single'
                    bar(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, app.SamplePointSpinner.Value));
                case 'Multiple'
                    retreivedata = get(app.SelectDataTable,'data');
                    hold(app.NormalizedDataPlot,"on");
                    for k = 1:length(retreivedata(:,2))
                        if retreivedata(k,2) == 1
                            stem(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                        end
                    end
                    hold(app.NormalizedDataPlot,"off");
            end     
            Image_plotNormalizedData(app);
            Imaging_PlotImage(app);
        end
        
        function Image_plotNormalizedData(app)
            cla(app.Imaging_NormalizedDataPlot)
            % Plotting Normalized Data
            
            switch app.NormalizeDisplay
                case 'All'
                    hold(app.Imaging_NormalizedDataPlot,"on");
                    for k = 1:app.ColumnNumber
                        stem(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                    end
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                    hold(app.Imaging_NormalizedDataPlot,"off");
                case 'Single'
                    bar(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, app.SamplePointSpinner.Value));
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                case 'Multiple'
                    retreivedata = get(app.SelectDataTable,'data');
                    hold(app.Imaging_NormalizedDataPlot,"on");
                    for k = 1:length(retreivedata(:,2))
                        if retreivedata(k,2) == 1
                            stem(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                        end
                    end
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                    hold(app.Imaging_NormalizedDataPlot,"off");
            end
        end
        
        function DisplayDataTable(app)
            NormData = transpose(app.NormalizedArray);
            %SampleIndex = transpose(1:app.ColumnNumber);
            %DataToDisplay = cat(2,SampleIndex,NormData);
            app.UITable.RowName = 'numbered';
            app.UITable.ColumnName = 'numbered';

            app.UITable.Data = NormData;
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s);
        end
        
        function DisplayDataTable2(app)
            NormData = transpose(app.NormalizedArray);
            %SampleIndex = transpose(1:app.ColumnNumber);
            %DataToDisplay = cat(2,SampleIndex,NormData);
            app.UITable_2.RowName = 'numbered';
            app.UITable_2.ColumnName = 'numbered';

            app.UITable_2.Data = NormData;
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable_2,s);
        end
        
        function DisplaySamplePointOption(app)
            SampleIndex = transpose(1:app.ColumnNumber);
            F = false(app.ColumnNumber,1);
            F = [SampleIndex F];
            app.SelectDataTable.Data = F;
            app.SelectDataTable.ColumnFormat = {'char', 'logical'};
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.SelectDataTable,s);
        end
        
        function Imaging_PlotImage(app)
            FeatureRow = app.NormalizedArray(app.FeaturePeak, :);
            app.ImageArray = transpose(reshape(FeatureRow,9,9,[]));
            maxy = max(app.NormalizedArray,[],'all');
            imaging = imagesc(app.Imaging_Display,app.ImageArray);
            colorbar(app.Imaging_Display);
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
            set(imaging, 'ButtonDownFcn', {@ImageClickCallback});
            
                function ImageClickCallback ( objectHandle , eventData )
                    temp = app.Imaging_Display.CurrentPoint;
                    temp = temp(1,1:2);
                    x = round(temp(1));
                    y = round(temp(2));
                    Imaging_PlotSpectrum(app,x,y,maxy);
                end
  
        end
        function Imaging_PlotSpectrum(app,xcoordinate,ycoordinate,ylimit)
            index = ((ycoordinate-1)*9)+xcoordinate;
            barchart = bar(app.Imaging_SelectedDataPlot, app.XAxisPlot, app.NormalizedArray(:, index));
            barchart.FaceColor = 'flat';
            barchart.CData(app.FeaturePeak,:) = [1 0 0];
            ylim(app.Imaging_SelectedDataPlot,[0, ylimit]);
        end
        
        function DEMO_DisplayClassOption(app)
            SampleIndex = transpose(["class1","class2","class3"]);
            F = false(3,1);
            F = [SampleIndex F];
            app.SelectClassTable.Data = F;
            app.SelectClassTable.ColumnFormat = {'char', 'logical'};
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.SelectClassTable,s);
            DEMO_Analysis_Image(app);
            DEMO_PlotClassHistogram(app);
        end
        
        function DEMO_Analysis_Image(app)
            %test
            C = zeros(9,9,3);
            retreivedata = get(app.SelectClassTable,'data');
            disp(retreivedata);
            if retreivedata(1,2)=="1"
                img1 = rescale(reshape(app.NormalizedArray(12, :),9,9,[]));
                C(:,:,1) = img1;
                disp("hello");
            end
            if retreivedata(2,2)=="1"
                img2 = rescale(reshape(app.NormalizedArray(29, :),9,9,[]));
                C(:,:,2) = img2;
            end
            if retreivedata(3,2)=="1"
                img3 = rescale(reshape(app.NormalizedArray(40, :),9,9,[]));
                C(:,:,3) = img3;
            end
            imaging = image(app.Imaging_Display_2,C);
            
            set(imaging, 'ButtonDownFcn', {@ImageClickCallback});
            
                function ImageClickCallback ( objectHandle , eventData )
                    temp = app.Imaging_Display_2.CurrentPoint;
                    temp = temp(1,1:2);
                    x = round(temp(1));
                    y = round(temp(2));
                    DEMO_Imaging_PlotSpectrum(app,x,y);
                end
        end
        function DEMO_Imaging_PlotSpectrum(app,xcoordinate,ycoordinate)
            index = ((ycoordinate-1)*9)+xcoordinate;
            barchart = bar(app.UIAxes2, app.XAxisPlot, app.NormalizedArray(:, index));
            barchart.FaceColor = 'flat';
            barchart.CData(12,:) = [1 0 0];
            barchart.CData(29,:) = [0 1 0];
            barchart.CData(40,:) = [0 0 1];

        end
        function DEMO_PlotClassHistogram(app)
            class1Data = app.NormalizedArray(12, :);
            class2Data = app.NormalizedArray(29, :);
            class3Data = app.NormalizedArray(40, :);
            histogram(app.UIAxes,class1Data);
            histogram(app.UIAxes_2,class2Data);
            histogram(app.UIAxes_3,class3Data);
        end
        
        % Sabai part
        function setDefaultPeakDetection(app)
            app.Base = 12;
            app.Multiplier = 10;
            app.HeightFilter = 1;
            app.PeakThreshold = 500;
        end
        
        function findpeak(app)
            app.DetectedPeak = mspeaks(app.PreproceesedMzValues,app.PreproceesedSpectraIntensities,'DENOISING',false,'BASE',app.Base,'MULTIPLIER',app.Multiplier,'HEIGHTFILTER',app.HeightFilter);
            app.CutThresholdPeak = cellfun(@(p) p(p(:,2)>app.PeakThreshold,:),app.DetectedPeak,'Uniform',false);         
        end
        
        function binning(app)
            allPeaks = cell2mat(app.CutThresholdPeak);
            numPeaks = cellfun(@(x) length(x),app.CutThresholdPeak);
            Sidx = accumarray(cumsum(numPeaks),1);
            Sidx = cumsum(Sidx)-Sidx;
            
            distfun = @(x,y) (x(:,1)-y(:,1)).^2 + (x(:,2)==y(:,2))*10^6;
            tree = linkage(pdist([allPeaks(:,1),Sidx],distfun));
            clusters = cluster(tree,'CUTOFF',75,'CRITERION','Distance');
            
            app.CMZ = accumarray(clusters,prod(allPeaks,2))./accumarray(clusters,allPeaks(:,2));
            app.PR = accumarray(clusters,allPeaks(:,2),[],@max);
            [app.CMZ,h] = sort(app.CMZ);
            app.PR = app.PR(h);
        end
        
        function binning2(app)
            allPeaks = cell2mat(app.CutThresholdPeak);
            numPeaks = cellfun(@(x) length(x),app.CutThresholdPeak);
            Sidx = accumarray(cumsum(numPeaks),1);
            Sidx = cumsum(Sidx)-Sidx;
            distfun = @(x,y) (x(:,1)-y(:,1)).^2 + (x(:,2)==y(:,2))*10^6
            tree = linkage(pdist([allPeaks(:,1),Sidx],distfun));
            clusters = cluster(tree,'CUTOFF',75,'CRITERION','Distance');
            app.CMZ = accumarray(clusters,prod(allPeaks,2))./accumarray(clusters,allPeaks(:,2));
            app.PR = accumarray(clusters,allPeaks(:,2),[],@max);
            [app.CMZ,h] = sort(app.CMZ);
            app.PR = app.PR(h);
        end
        
        function MockPeakBinning(app)
           
         
            %Get MZ from column1 and intensity of spectra1
            MZ = app.PreproceesedMzValues;
            Y = app.PreproceesedSpectraIntensities(:,1);
       
            YB = msbackadj(MZ,Y);
            P = [759.2 785.2 807.2 835.2]; % M/Z location of reference peaks
            W = [60 100 60 100];         % Weight for reference peaks
            YA = msalign(MZ,YB,P,'WEIGHTS',W);
            
            YN1 = msnorm(MZ,YA,'QUANTILE',1,'MAX',100);
            YN2 = msnorm(MZ,YA,'LIMITS',[1000 inf],'MAX',100);
            YS = mssgolay(MZ,YN1);
            %P1 = mspeaks(MZ,YS,'DENOISING',false,'HEIGHTFILTER',2,'SHOWPLOT',1)
            P2 = mspeaks(MZ,YN1,'BASE',12,'MULTIPLIER',10,'HEIGHTFILTER',1)
            
            P3 = cellfun( @(x) x(x(:,2)>5,:),{P2},'UNIFORM',false)
            allPeaks = cell2mat(P3);
            numPeaks = cellfun(@(x) length(x),P3);
            Sidx = accumarray(cumsum(numPeaks),1);
            Sidx = cumsum(Sidx)-Sidx;
            
            distfun = @(x,y) (x(:,1)-y(:,1)).^2 + (x(:,2)==y(:,2))*10^6;
            
            tree = linkage(pdist([allPeaks(:,1),Sidx],distfun));
            clusters = cluster(tree,'CUTOFF',75,'CRITERION','Distance');
            
            CMZ = accumarray(clusters,prod(allPeaks,2))./accumarray(clusters,allPeaks(:,2));
            
            PR = accumarray(clusters,allPeaks(:,2),[],@max);
            
            [CMZ,h] = sort(CMZ);
            PR = PR(h);
         
            
            PA = nan(numel(CMZ),1);
            for i = 1:1
                [j,k] = samplealign([CMZ PR],P3{i},'BAND',15,'WEIGHTS',[1 .1]);
                PA(j,i) = P3{i}(k,2);
            end
            
         
            plot(app.UIAxes_7,[CMZ CMZ],[-10 100],':k',MZ,YN1,'b',CMZ,PA,'o')
            %plot(MZ,YN1)
            %plot(CMZ,PA,'o')
            xlabel('Mass/Charge (M/Z)')
            ylabel('Relative Intensity')
            title('Peaks Aligned to the Common Mass/Charge (M/Z) Reference')


        end
        function DisplayPeakDetection(app)
                findpeak(app);
                if isempty(app.SpectrumtodisplayEditField.Value)
                    plot(app.UIAxes_5,app.PreproceesedMzValues,app.PreproceesedSpectraIntensities(:,2),'b',app.CutThresholdPeak{2}(:,1),app.CutThresholdPeak{2}(:,2),'ro')
                else
                    app.UIAxes.Title.String = 'Selected spectra';
                    plot(app.UIAxes_5,app.PreproceesedMzValues,app.PreproceesedSpectraIntensities,'b',app.CutThresholdPeak{1}(:,1),app.CutThresholdPeak{1}(:,2),'ro')
                end
        end
        function DisplayPeakBinning(app)
                binning(app);          
                if isempty(app.SpectrumtodisplayEditField_2.Value)
                    hold on
                    box on
                    plot([app.CMZ app.CMZ],[-10 100],'-k') %,app.PreproceesedMzValues,app.PreproceesedSpectraIntensities,'b'
                    %plot([app.CMZ app.CMZ],[-10 100],':k',app.PreproceesedMzValues,app.PreproceesedSpectraIntensities,'b')
                    %plot(app.UIAxes_7,[app.CMZ app.CMZ],[-10 100],':k',app.PreproceesedMzValues,app.PreproceesedSpectraIntensities(:,2),'b')
                    %plot(app.UIAxes_2,app.mz,app.y,'b',app.CMZ,app.PA,'o')
                else
                    app.UIAxes.Title.String = 'Selected spectra';
                    figure
                    hold on
                    box on
                    plot(app.UIAxes_7,[app.CMZ app.CMZ],[-10 100],'-k')
                    %plot(app.UIAxes_2,app.mz,app.y,'b',app.CMZ,app.PA,'o')
                  
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FeaturePeakSlider.MajorTicks = (1:5:76);
            app.FeaturePeakSlider.MinorTicks = (1:76);
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            app.PeakSpinner.Enable = false;
            app.pvalueSpinner.Enable = false;
            app.SamplePointSpinner.Enable = false;
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)

                [file, path] = uigetfile('*.csv');
                if isequal(file,0)
                    msgbox('Please input CSV files')
                else
                    app.FileName = file;
                    app.DataArray3 = csvread(fullfile(path,file));
                    % Data Array Preprocess and Properties
                    % column 1 is used for plotting the x-axis
                    app.XAxisPlot = app.DataArray3(:, 1);
                    % removing the first column from the Array
                    app.DataArray3(:,1) = [];
                    % removing last col ( all 0s )
                    % app.DataArray3(:,81) = [];
                    
                    %plotting the raw data
                    [app.RowNumber,app.ColumnNumber]= size(app.DataArray3);
                    hold(app.RawDataPlot,"on");
                    for i = 1:app.ColumnNumber
                        stem(app.RawDataPlot,app.XAxisPlot,app.DataArray3(:, i));
                    end
                    hold(app.RawDataPlot,"off");
                    
                    updateNormalizedArray(app);
                    setDefaultReferencePeak(app);
                    DisplaySamplePointOption(app);
                    DEMO_DisplayClassOption(app);
                    DisplayDataTable2(app);
                end
           
        end

        % Selection changed function: 
        % NormalizationMethodsButtonGroup
        function NormalizationMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationMethodsButtonGroup.SelectedObject;
            app.PeakSpinner.Enable = false;
            app.pvalueSpinner.Enable = false;
            switch selectedButton % Get Tag of selected object.
                case app.ioncountsButton
                    app.NormalizeMethod='Sum';
                case app.pnormButton
                    app.NormalizeMethod='Norm';
                    app.pvalueSpinner.Enable = true;
                case app.MedianButton
                    app.NormalizeMethod='Median';
                case app.NoiseLevelButton
                    app.NormalizeMethod='Noise';
                otherwise
                    app.NormalizeMethod='Peak';
                    app.PeakSpinner.Enable = true;
            end
            app.NowSelectLabel.Text = app.NormalizeMethod;
            updateNormalizedArray(app);
        end

        % Value changed function: FeaturePeakSlider
        function FeaturePeakSliderValueChanged(app, event)
            value = app.FeaturePeakSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx); 
            app.FeaturePeak = value;
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            plotNormalizedData(app);
        end

        % Value changed function: PeakSpinner
        function PeakSpinnerValueChanged(app, event)
            value = app.PeakSpinner.Value;
            app.ReferencePeak = value;
            updateNormalizedArray(app);
        end

        % Button pushed function: GeneratePDFReportButton
        function GeneratePDFReportButtonPushed(app, event)
            
           
             % Import report API classes (optional)
            import mlreportgen.report.*
            
            % Add report container (required)
            rpt = Report('output','pdf');
            
            % Add content to container (required)
            % Types of content added here: title 
            % page and table of contents reporters
            titlepg = TitlePage;
            titlepg.Title = 'MSPEC Report';
            titlepg.Author = sprintf('From: %s',app.FileName);
            add(rpt,titlepg);
            
            % Add content to report sections (optional)
            % Text and formal image added to chapter
            chap1 = Chapter('Normalization');
            add(chap1,['Method: ',app.NormalizeMethod]);
            
            f1 = figure('visible','off');
            %plotting the raw data
            subplot(2,1,1,"Units","pixels");
            hold on;
            for i = 1:app.ColumnNumber
                plot(app.XAxisPlot,app.DataArray3(:, i));
            end
            hold off;
            title('Raw Data');
            % Normalized
            subplot(2,1,2,"Units","pixels");
            hold on;
            for k = 1:app.ColumnNumber
               plot(app.XAxisPlot, app.NormalizedArray(:, k))
            end
            %xline(app.FeaturePeak,'-.r',{'Selected','Peak'});
            hold off;
            title('Normalized Data');
            
            add(chap1,Figure(f1));
            add(rpt,chap1);
            
            
            % Close the report (required)
            close(rpt);
            % Display the report (optional)
            rptview(rpt);
        end

        % Button pushed function: ExportCSVNormalizedDataButton
        function ExportCSVNormalizedDataButtonPushed(app, event)
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            Arr = transpose(0:app.RowNumber-1);
            OutputArray = [Arr app.NormalizedArray];
            csvwrite(filename,OutputArray);
        end

        % Value changed function: pvalueSpinner
        function pvalueSpinnerValueChanged(app, event)
            value = app.pvalueSpinner.Value;
            app.NormalizationNormValue = value;
            updateNormalizedArray(app);
        end

        % Callback function
        function DegreeSpinnerValueChanged(app, event)
            value = app.DegreeSpinner.Value;
            app.RegressionDegree = value;
        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            app.SamplePointSpinner.Enable = false;
            app.SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.AllSpectraButton
                    app.NormalizeDisplay='All';
                case app.SingleSpectrumButton
                    app.NormalizeDisplay='Single';
                    app.SamplePointSpinner.Enable = true;
                otherwise
                    app.NormalizeDisplay='Multiple';
                    app.SelectDataTable.Enable = 'on';
            end
            plotNormalizedData(app);
        end

        % Value changed function: SamplePointSpinner
        function SamplePointSpinnerValueChanged(app, event)
            plotNormalizedData(app);
        end

        % Cell edit callback: SelectDataTable
        function SelectDataTableCellEdit(app, event)
            plotNormalizedData(app);
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
        end

        % Cell edit callback: SelectClassTable
        function SelectClassTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            DEMO_Analysis_Image(app);
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            value = app.WindowSizeEditField.Value;
            app.WindowSize = value;
        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            value = app.StepsizeEditField.Value;
            app.StepSize = value;
        end

        % Value changed function: QuantilevalueEditField
        function QuantilevalueEditFieldValueChanged(app, event)
            value = app.QuantilevalueEditField.Value;
            app.QuantileValue = value;
        end

        % Value changed function: ReferenceSpectrumEditField
        function ReferenceSpectrumEditFieldValueChanged(app, event)
            value = app.ReferenceSpectrumEditField.Value;
            app.ReferenceSpectrum = value;
        end

        % Value changed function: 
        % MinimumsegementsizeallowedEditField
        function MinimumsegementsizeallowedEditFieldValueChanged(app, event)
            value = app.MinimumsegementsizeallowedEditField.Value;
            app.SegmentSize = value;
        end

        % Value changed function: MaximumshiftallowedEditField
        function MaximumshiftallowedEditFieldValueChanged(app, event)
            value = app.MaximumshiftallowedEditField.Value;
            app.ShiftAllowance = value;
        end

        % Value changed function: SpectrumtodisplayEditField
        function SpectrumtodisplayEditFieldValueChanged(app, event)
             value = app.SpectrumtodisplayEditField.Value;
             app.DisplayingSpectra = str2num(cell2mat(split(value,',')));
        end

        % Value changed function: StartingpointEditField
        function StartingpointEditFieldValueChanged(app, event)
            value = app.StartingpointEditField.Value;
            app.SectionStart = str2num(value);
        end

        % Value changed function: EndingpointEditField
        function EndingpointEditFieldValueChanged(app, event)
            value = app.EndingpointEditField.Value;
            app.SectionEnd = str2num(value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                baseline(app);
                
                %[m,iStart] = min(abs(app.RawMzValues-(app.SectionStart)));
                %[rowStart,colStart] = find(app.RawMzValues,startValue);
                %app.SectionStart = rowStart;
                %[n,iEnd] = min(abs(app.RawMzValues-(app.SectionEnd)));
                %[rowEnd,colEnd] = find(app.RawMzValues,endValue);
                %app.SectionEnd = colEnd;
                %app.SectionOfInterest = app.RawMzValues(app.iStart:app.iEnd,:);
                %app.AlignedSectionOfInterest = app.AlignedSpectra(:,app.iStart:app.iEnd);
                if isempty(app.StartingpointEditField.Value)
                    app.SectionStart = app.MinIntensity;
                end
                if isempty(app.EndingpointEditField.Value)
                    app.SectionEnd = app.MaxIntensity;
                end
                app.UIAxes_4.XLim = [app.SectionStart app.SectionEnd];
                if isempty(app.SpectrumtodisplayEditField.Value)
                    cla(app.UIAxes_4)
                    plot(app.UIAxes_4,app.RawMzValues,app.BaselinedSpectra)
                    %plot(app.UIAxes,app.SectionOfInterest,app.AlignedSectionOfInterest)
                else
                    cla(app.UIAxes_4)
                    plot(app.UIAxes_4,app.RawMzValues,app.BaselinedSpectra(:,app.DisplayingSpectra))                   
                end
        end

        % Menu selected function: ImportDataMenu_2
        function ImportDataMenu_2Selected(app, event)
                handles.filename = uigetfile('*.*');
                filename=handles.filename;
                [fPath, fName, fExt] = fileparts(filename);
                switch lower(fExt)
                  case '.ods'
                    app.RawImportData=xlsread(filename);
                  case '.csv'	
                    app.RawImportData=readmatrix(filename);
                  otherwise  % Under all circumstances SWITCH gets an OTHERWISE!
                    error('Unexpected file extension: %s', fExt);
                end
    
                app.RawMzValues=app.RawImportData(:,1);
                [x,y] = size(app.RawImportData);
                for i = 2:y
                    app.RawSpectraIntensities(:,i)  = app.RawImportData(:,i);
                end
                app.RawSpectraIntensities(:,1)=[]
                app.MinIntensity = min(app.RawMzValues);
                app.MaxIntensity = max(app.RawMzValues);
                [m,n] = size(app.RawSpectraIntensities);
                app.NumberOfSpectra = n;
                
                app.NumberofspectraEditField.Value = app.NumberOfSpectra;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
                handles.filename = uigetfile('*.*');
                filename=handles.filename;
                [fPath, fName, fExt] = fileparts(filename);
                switch lower(fExt)
                  case '.ods'
                    app.PreproceesedImportData=xlsread(filename);
                  case '.csv'	
                    app.PreproceesedImportData=readmatrix(filename);
                  otherwise  % Under all circumstances SWITCH gets an OTHERWISE!
                    error('Unexpected file extension: %s', fExt);
                end
    
                app.PreproceesedMzValues=app.PreproceesedImportData(:,1);
                [x,y] = size(app.PreproceesedImportData);
                for i = 2:y
                    app.PreproceesedSpectraIntensities(:,i-1)  = app.PreproceesedImportData(:,i);
                end
                %app.PreproceesedSpectraIntensities(:,1)=[]
              
                [m,n] = size(app.PreproceesedSpectraIntensities);
                setDefaultPeakDetection(app)
                DisplayPeakDetection(app)
                MockPeakBinning(app)
                DisplayDataTable2(app)
                %DisplayPeakBinning(app)
                %app.NumberOfSpectra = n;
                
                %app.NumberofspectraEditField.Value = app.NumberOfSpectra;
        end

        % Button pushed function: ExportCSVButton_2
        function ExportCSVButton_2Pushed(app, event)
            export = horzcat(app.RawMzValues,app.BaselinedSpectra);
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            csvwrite(filename,export);
        end

        % Value changed function: BASEEditField
        function BASEEditFieldValueChanged(app, event)
            value = app.BASEEditField.Value;
            app.Base = value;
            DisplayPeakDetection(app)
        end

        % Value changed function: MULTIPLIEREditField
        function MULTIPLIEREditFieldValueChanged(app, event)
            value = app.MULTIPLIEREditField.Value;
            app.Multiplier = value;
            DisplayPeakDetection(app)
        end

        % Value changed function: HEIGHTFILTEREditField
        function HEIGHTFILTEREditFieldValueChanged(app, event)
            value = app.HEIGHTFILTEREditField.Value;
            app.HeightFilter = value;
            DisplayPeakDetection(app)
        end

        % Value changed function: PeakThresholdEditField
        function PeakThresholdEditFieldValueChanged(app, event)
            value = app.PeakThresholdEditField.Value;
            app.PeakThreshold = value;
            DisplayPeakDetection(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSPECAppUIFigure = uifigure('Visible', 'off');
            app.MSPECAppUIFigure.Name = 'MSPEC App';
            app.Menu = uimenu(app.MSPECAppUIFigure);
            app.Menu.Text = 'Menu';
            app.ImportDataMenu_2 = uimenu(app.Menu);
            app.ImportDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ImportDataMenu_2Selected, true);
            app.ImportDataMenu_2.Text = 'Import Data';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.MSPECAppUIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.NumberofspectraEditFieldLabel = uilabel(app.PreprocessingTab);
            app.NumberofspectraEditFieldLabel.Text = 'Number of spectra';
            app.NumberofspectraEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.NumberofspectraEditField.Editable = 'off';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.QuantilevalueEditField.ValueChangedFcn = createCallbackFcn(app, @QuantilevalueEditFieldValueChanged, true);
            app.AlignmentPanel = uipanel(app.PreprocessingTab);
            app.AlignmentPanel.Title = 'Alignment';
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.AlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.ReferenceSpectrumEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.ReferenceSpectrumEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceSpectrumEditFieldValueChanged, true);
            app.MinimumsegementsizeallowedLabel = uilabel(app.AlignmentPanel);
            app.MinimumsegementsizeallowedLabel.Text = {'Minimum segement size '; 'allowed'};
            app.MinimumsegementsizeallowedEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.MinimumsegementsizeallowedEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumsegementsizeallowedEditFieldValueChanged, true);
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.AlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.MaximumshiftallowedEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.MaximumshiftallowedEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumshiftallowedEditFieldValueChanged, true);
            app.PlotPanel = uipanel(app.PreprocessingTab);
            app.PlotPanel.Title = 'Plot';
            app.SpectrumtodisplayEditFieldLabel = uilabel(app.PlotPanel);
            app.SpectrumtodisplayEditFieldLabel.Text = 'Spectrum to display';
            app.SpectrumtodisplayEditField = uieditfield(app.PlotPanel, 'text');
            app.SpectrumtodisplayEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumtodisplayEditFieldValueChanged, true);
            app.SectionofinterestLabel = uilabel(app.PlotPanel);
            app.SectionofinterestLabel.Text = 'Section of interest:';
            app.PlotButton = uibutton(app.PlotPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExportCSVButton_2 = uibutton(app.PlotPanel, 'push');
            app.ExportCSVButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButton_2Pushed, true);
            app.ExportCSVButton_2.Text = 'Export CSV';
            app.StartingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.StartingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.StartingpointEditField.ValueChangedFcn = createCallbackFcn(app, @StartingpointEditFieldValueChanged, true);
            app.EndingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.EndingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.EndingpointEditField.ValueChangedFcn = createCallbackFcn(app, @EndingpointEditFieldValueChanged, true);
            app.UIAxes_4 = uiaxes(app.PreprocessingTab);
            title(app.UIAxes_4, 'Pre-Processed Mass Spectra')
            xlabel(app.UIAxes_4, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes_4, 'Relative Intensity')
            app.PeakDetectionTab = uitab(app.TabGroup);
            app.PeakDetectionTab.Title = 'Peak Detection';
            app.PeakDetectionPanel = uipanel(app.PeakDetectionTab);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.BASEEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.BASEEditFieldLabel.Text = 'BASE';
            app.BASEEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.BASEEditField.ValueChangedFcn = createCallbackFcn(app, @BASEEditFieldValueChanged, true);
            app.BASEEditField.Value = 12;
            app.HEIGHTFILTEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.HEIGHTFILTEREditFieldLabel.Text = 'HEIGHTFILTER';
            app.HEIGHTFILTEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.HEIGHTFILTEREditField.ValueChangedFcn = createCallbackFcn(app, @HEIGHTFILTEREditFieldValueChanged, true);
            app.HEIGHTFILTEREditField.Value = 1;
            app.MULTIPLIEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.MULTIPLIEREditFieldLabel.Text = 'MULTIPLIER';
            app.MULTIPLIEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.MULTIPLIEREditField.ValueChangedFcn = createCallbackFcn(app, @MULTIPLIEREditFieldValueChanged, true);
            app.MULTIPLIEREditField.Value = 10;
            app.PeakThresholdEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.PeakThresholdEditFieldLabel.Text = 'Peak Threshold';
            app.PeakThresholdEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.PeakThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PeakThresholdEditFieldValueChanged, true);
            app.PeakThresholdEditField.Value = 300;
            app.SpectrumtodisplayEditField_3Label = uilabel(app.PeakDetectionPanel);
            app.SpectrumtodisplayEditField_3Label.Text = 'Spectrum to display';
            app.SpectrumtodisplayEditField_3 = uieditfield(app.PeakDetectionPanel, 'text');
            app.UIAxes_5 = uiaxes(app.PeakDetectionTab);
            title(app.UIAxes_5, 'Peak Detection')
            xlabel(app.UIAxes_5, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes_5, 'Relative Intensity')
            app.BiningPanel = uipanel(app.PeakDetectionTab);
            app.BiningPanel.Title = 'Bining';
            app.CUTOFFEditFieldLabel = uilabel(app.BiningPanel);
            app.CUTOFFEditFieldLabel.Text = 'CUTOFF';
            app.CUTOFFEditField = uieditfield(app.BiningPanel, 'numeric');
            app.MethodDropDownLabel = uilabel(app.BiningPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.BiningPanel);
            app.MethodDropDown.Items = {'Hierarchical Clustering', 'Dynamic Programming'};
            app.MethodDropDown.ItemsData = {'1'};
            app.MethodDropDown.Value = '1';
            app.CriterionDropDownLabel = uilabel(app.BiningPanel);
            app.CriterionDropDownLabel.Text = 'Criterion';
            app.CriterionDropDown = uidropdown(app.BiningPanel);
            app.CriterionDropDown.Items = {'DISTANCE'};
            app.CriterionDropDown.ItemsData = {'distance'};
            app.CriterionDropDown.Value = 'distance';
            app.SpectrumtodisplayEditField_2Label = uilabel(app.BiningPanel);
            app.SpectrumtodisplayEditField_2Label.Text = 'Spectrum to display';
            app.SpectrumtodisplayEditField_2 = uieditfield(app.BiningPanel, 'text');
            app.UIAxes_7 = uiaxes(app.PeakDetectionTab);
            title(app.UIAxes_7, 'Peak Binning')
            xlabel(app.UIAxes_7, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes_7, 'Relative Intensity')
            app.UITable_2 = uitable(app.PeakDetectionTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.ExportCSVButton = uibutton(app.PeakDetectionTab, 'push');
            app.ExportCSVButton.Text = {'Export  CSV'; ' '};
            app.DataNormalizationTab = uitab(app.TabGroup);
            app.DataNormalizationTab.Title = 'Data Normalization';
            app.RawDataPlot = uiaxes(app.DataNormalizationTab);
            title(app.RawDataPlot, 'Raw Data')
            xlabel(app.RawDataPlot, 'm/z')
            ylabel(app.RawDataPlot, 'Intensity')
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.DataNormalizationTab);
            app.NormalizationMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationMethodsButtonGroupSelectionChanged, true);
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.ioncountsButton.Text = 'ion counts';
            app.ioncountsButton.Value = true;
            app.ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.ReferencePeakButton.Text = 'Reference Peak';
            app.PeakSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.PeakSpinnerLabel.Text = 'Peak';
            app.PeakSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.PeakSpinner.Limits = [0 76];
            app.PeakSpinner.ValueChangedFcn = createCallbackFcn(app, @PeakSpinnerValueChanged, true);
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinner.Limits = [1 Inf];
            app.pvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @pvalueSpinnerValueChanged, true);
            app.pvalueSpinner.Value = 1;
            app.ExclusionofmassrangesCheckBox = uicheckbox(app.NormalizationMethodsButtonGroup);
            app.ExclusionofmassrangesCheckBox.Enable = 'off';
            app.ExclusionofmassrangesCheckBox.Text = 'Exclusion of mass ranges';
            app.LowerEditFieldLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.LowerEditFieldLabel.Enable = 'off';
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.LowerEditField.Enable = 'off';
            app.UpperEditFieldLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.UpperEditFieldLabel.Enable = 'off';
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.UpperEditField.Enable = 'off';
            app.NowSelectLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.NowSelectLabel.Text = 'Now Select';
            app.pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.pnormButton.Text = 'p-norm';
            app.MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.MedianButton.Text = 'Median';
            app.NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.NoiseLevelButton.Text = 'Noise Level';
            app.NormalizedDataPlot = uiaxes(app.DataNormalizationTab);
            title(app.NormalizedDataPlot, 'Normalized Data')
            xlabel(app.NormalizedDataPlot, 'm/z')
            ylabel(app.NormalizedDataPlot, 'Intensity')
            app.ViewOptionButtonGroup = uibuttongroup(app.DataNormalizationTab);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.AllSpectraButton.Text = 'All Spectra';
            app.AllSpectraButton.Value = true;
            app.SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.SamplePointSpinner.Limits = [1 80];
            app.SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplePointSpinnerValueChanged, true);
            app.SamplePointSpinner.Value = 1;
            app.SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.SelectDataTable.RowName = {};
            app.SelectDataTable.ColumnSortable = true;
            app.SelectDataTable.ColumnEditable = [false true];
            app.SelectDataTable.CellEditCallback = createCallbackFcn(app, @SelectDataTableCellEdit, true);
            app.SelectDataTable.Enable = 'off';
            app.MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.MultipleSpectraButton.Text = 'Multiple Spectra';
            app.ExportFilesPanel = uipanel(app.DataNormalizationTab);
            app.ExportFilesPanel.Title = 'Export Files';
            app.GeneratePDFReportButton = uibutton(app.ExportFilesPanel, 'push');
            app.GeneratePDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFReportButtonPushed, true);
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.ExportCSVNormalizedDataButton = uibutton(app.ExportFilesPanel, 'push');
            app.ExportCSVNormalizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVNormalizedDataButtonPushed, true);
            app.ExportCSVNormalizedDataButton.Text = {'Export  CSV'; 'Normalized Data '};
            app.UITable = uitable(app.DataNormalizationTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.Imaging_NormalizedDataPlot = uiaxes(app.ImagingTab);
            title(app.Imaging_NormalizedDataPlot, 'Normalized Data')
            xlabel(app.Imaging_NormalizedDataPlot, 'm/z')
            ylabel(app.Imaging_NormalizedDataPlot, 'Relative Intensity')
            app.FeaturePeakLabel = uilabel(app.ImagingTab);
            app.FeaturePeakLabel.Text = {'Feature'; 'Peak'};
            app.FeaturePeakSlider = uislider(app.ImagingTab);
            app.FeaturePeakSlider.Limits = [1 76];
            app.FeaturePeakSlider.ValueChangedFcn = createCallbackFcn(app, @FeaturePeakSliderValueChanged, true);
            app.FeaturePeakSlider.MinorTicks = [];
            app.FeaturePeakSlider.Value = 1;
            app.SelectedPeakLabel = uilabel(app.ImagingTab);
            app.SelectedPeakLabel.Text = 'Selected Peak:';
            app.Imaging_Display = uiaxes(app.ImagingTab);
            title(app.Imaging_Display, '')
            xlabel(app.Imaging_Display, '')
            ylabel(app.Imaging_Display, '')
            app.ColorMapDropDownLabel = uilabel(app.ImagingTab);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColorMapDropDown = uidropdown(app.ImagingTab);
            app.ColorMapDropDown.Items = {'gray', 'parula', 'hsv', 'winter'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'gray';
            app.Imaging_SelectedDataPlot = uiaxes(app.ImagingTab);
            title(app.Imaging_SelectedDataPlot, 'Selected Datapoint')
            xlabel(app.Imaging_SelectedDataPlot, 'm/z')
            ylabel(app.Imaging_SelectedDataPlot, 'Relative Intensity')
            app.ColorScaleOptionListBoxLabel = uilabel(app.ImagingTab);
            app.ColorScaleOptionListBoxLabel.Text = {'Color Scale'; 'Option'};
            app.ColorScaleOptionListBox = uilistbox(app.ImagingTab);
            app.ColorScaleOptionListBox.Items = {'Full Scale', '0 to max', 'custom'};
            app.ColorScaleOptionListBox.Value = 'Full Scale';
            app.AnalyticsTab = uitab(app.TabGroup);
            app.AnalyticsTab.Title = 'Analytics';
            app.SelectClassTable = uitable(app.AnalyticsTab);
            app.SelectClassTable.ColumnName = {'Class'; 'Select'};
            app.SelectClassTable.RowName = {};
            app.SelectClassTable.ColumnEditable = [false true];
            app.SelectClassTable.CellEditCallback = createCallbackFcn(app, @SelectClassTableCellEdit, true);
            app.Imaging_Display_2 = uiaxes(app.AnalyticsTab);
            title(app.Imaging_Display_2, '')
            xlabel(app.Imaging_Display_2, '')
            ylabel(app.Imaging_Display_2, '')
            app.UIAxes = uiaxes(app.AnalyticsTab);
            title(app.UIAxes, 'Class 1')
            xlabel(app.UIAxes, 'intensity')
            ylabel(app.UIAxes, 'count')
            app.UIAxes_2 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes_2, 'Class 2')
            xlabel(app.UIAxes_2, 'intensity')
            ylabel(app.UIAxes_2, 'count')
            app.UIAxes_3 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes_3, 'Class 3')
            xlabel(app.UIAxes_3, 'intensity')
            ylabel(app.UIAxes_3, 'count')
            app.UIAxes2 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes2, 'Sample Point')
            xlabel(app.UIAxes2, 'm/z')
            ylabel(app.UIAxes2, 'Intensity')
            % Show the figure after all components are created
            app.MSPECAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mix_Prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSPECAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSPECAppUIFigure)
        end
    end
end